classdef StokesSimulator < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                       matlab.ui.Figure
    TabGroup                       matlab.ui.container.TabGroup
    AtmosphereTab                  matlab.ui.container.Tab
    XMinBoxAir                     matlab.ui.control.NumericEditField
    XMaxBoxAir                     matlab.ui.control.NumericEditField
    XLimSliderAir                  matlab.ui.control.RangeSlider
    XLogScaleCheckBox              matlab.ui.control.CheckBox
    YLogScaleCheckBox              matlab.ui.control.CheckBox
    DisplayEquationCheckBox        matlab.ui.control.CheckBox
    ModelParametersPanelAir        matlab.ui.container.Panel
    AspectRatioSlider              matlab.ui.control.Slider
    AspectRatioSliderLabel         matlab.ui.control.Label
    MineralogyDropDown             matlab.ui.control.DropDown
    ParticleMineralogyDropDownLabel  matlab.ui.control.Label
    ElevationSlider                matlab.ui.control.Slider
    ElevationmAGLSliderLabel       matlab.ui.control.Label
    ElevationDisplay               matlab.ui.control.TextArea
    DensityDisplay                 matlab.ui.control.TextArea
    AspectRatioDisplay             matlab.ui.control.TextArea
    UIAxesAir                      matlab.ui.control.UIAxes
    WaterTab                       matlab.ui.container.Tab
    XLogScaleCheckBoxWater         matlab.ui.control.CheckBox
    YLogScaleCheckBoxWater         matlab.ui.control.CheckBox
    DisplayEquationCheckBoxWater   matlab.ui.control.CheckBox
    XMinBoxWater                   matlab.ui.control.NumericEditField
    XMaxBoxWater                   matlab.ui.control.NumericEditField
    XLimSliderWater                matlab.ui.control.RangeSlider
    ModelParametersPanelWater      matlab.ui.container.Panel
    WaterViscosityDisplay          matlab.ui.control.TextArea
    DynamicviscosityTextAreaLabel  matlab.ui.control.Label
    WaterDensityDisplay            matlab.ui.control.TextArea
    DensityTextAreaLabel           matlab.ui.control.Label
    TemperatureDisplay             matlab.ui.control.TextArea
    TemperatureSlider              matlab.ui.control.Slider
    TemperatureofthewaterSliderLabel  matlab.ui.control.Label
    MineralogyDropDownWater        matlab.ui.control.DropDown
    ParticleMineralogyLabel        matlab.ui.control.Label
    ParticleDensityDisplayWater    matlab.ui.control.TextArea
    UIAxesWater                    matlab.ui.control.UIAxes
  end

  
  properties (Access = private)
    %
    % MISCELLANEOUS
    %
    XVals % x-axis values (full scale)

    %
    % ATMOSPHERE
    %
    rho_p % Density of an average particle (kg/m^3)
    plt % Plot handle
    equation % Equation of the function

    %
    % WATER
    %
    rho_pw % Density of an average particle (kg/m^3)
    rho_fw % Density of the fluid (water; kg/m^3)
    mu_f   % Dynamic viscosity of the fluid (water; N s/m^2)
    pltw % Plot handle
    equationw % Equation of the function
  end
  
  methods (Access = private)
   
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      %
      % ATMOSPHERE TAB
      %
      % Define X values
      app.XVals = logspace(log10(0.01),log10(100));
      % Set display fields to "no edit"
      app.ElevationDisplay.Editable='off';
      app.DensityDisplay.Editable='off';
      app.AspectRatioDisplay.Editable='off';
      % Define particle density
      app.rho_p = particle_density(app.MineralogyDropDown.Value); % kg/m^3
      % Plot the settling velocity
      app.ElevationDisplay.Value = '0 m';
      C = mineral_color(app.MineralogyDropDown.Value);
      stokes_air_vals = stokes_law_model(app.XVals, app.rho_p, app.ElevationSlider.Value);
      app.plt=plot(app.UIAxesAir, app.XVals, stokes_air_vals,...
        'o-','LineWidth',1.5,'MarkerFaceColor','w','Color',C);
      app.UIAxesAir.XMinorTick = 'off';
      update_axis(app.UIAxesAir, 0.63, 16)
      % Set mineralogy density display
      app.DensityDisplay.Value = sprintf('%g kg/m^3', app.rho_p);
      % Set aspect ratio display
      app.AspectRatioDisplay.Value = sprintf('%0.1f',app.AspectRatioSlider.Value);

      %
      % WATER TAB
      %
      app.TemperatureDisplay.Value = sprintf('%g °C',app.TemperatureSlider.Value);
      app.WaterDensityDisplay.Value = sprintf('%g kg/m^3',...
                              water_density_model(app.TemperatureSlider.Value));
      app.WaterViscosityDisplay.Value = sprintf('%0.3f mPa/s',...
                       water_viscosity_model(app.TemperatureSlider.Value)*1000);
      app.rho_fw = water_density_model(app.TemperatureSlider.Value);
      app.mu_f = water_viscosity_model(app.TemperatureSlider.Value);
      app.rho_pw = particle_density(app.MineralogyDropDownWater.Value);
      app.ParticleDensityDisplayWater.Value = sprintf('%g kg/m^3', app.rho_pw);
      C2 = mineral_color(app.MineralogyDropDownWater.Value);
      stokes_water_vals = stokes_law_model_water(app.XVals, app.rho_pw, app.rho_fw, app.mu_f);
      app.pltw=plot(app.UIAxesWater,app.XVals,stokes_water_vals,...
        'd-','LineWidth',1.5,'MarkerFaceColor','w','Color',C2);
      app.UIAxesWater.XMinorTick = 'off';
      update_axis(app.UIAxesWater, 0.63, 16)
      % Set display fields to 'no edit'
      app.TemperatureDisplay.Editable='off';
      app.WaterDensityDisplay.Editable='off';
      app.WaterViscosityDisplay.Editable='off';
      app.ParticleDensityDisplayWater.Editable='off';
      
    end

    % Value changing function: XLimSliderAir
    function XLimSliderAirValueChanging(app, event)
      new_xlimits = app.XLimSliderAir.Value;
      app.XMinBoxAir.Value = new_xlimits(1);
      app.XMaxBoxAir.Value = new_xlimits(2);
      update_axis(app.UIAxesAir, new_xlimits(1), new_xlimits(2))
    end

    % Value changed function: XLimSliderAir
    function XLimSliderAirValueChanged(app, event)
      new_xlimits = app.XLimSliderAir.Value;
      app.XMinBoxAir.Value = new_xlimits(1);
      app.XMaxBoxAir.Value = new_xlimits(2);
      update_axis(app.UIAxesAir, new_xlimits(1), new_xlimits(2)) 
    end

    % Value changed function: XMinBoxAir
    function XMinBoxAirValueChanged(app, event)
      new_xmin = app.XMinBoxAir.Value;
      xlim_slider = app.XLimSliderAir.Value;
      app.XLimSliderAir.Value = [new_xmin xlim_slider(2)];
      update_axis(app.UIAxesAir, app.XLimSliderAir.Value(1), app.XLimSliderAir.Value(2))
    end

    % Value changed function: XMaxBoxAir
    function XMaxBoxAirValueChanged(app, event)
      new_xmax = app.XMaxBoxAir.Value;
      xlim_slider = app.XLimSliderAir.Value;
      app.XLimSliderAir.Value = [xlim_slider(1) new_xmax];
      update_axis(app.UIAxesAir, app.XLimSliderAir.Value(1), app.XLimSliderAir.Value(2))
    end

    % Value changed function: XLogScaleCheckBox
    function XLogScaleCheckBoxValueChanged(app, event)
      value = app.XLogScaleCheckBox.Value;
      if value
        app.UIAxesAir.XScale = "log";
        app.UIAxesAir.XMinorTick = "off";
      else
        app.UIAxesAir.XScale = "linear";
        app.UIAxesAir.XMinorTick = "off";
      end
    end

    % Value changed function: YLogScaleCheckBox
    function YLogScaleCheckBoxValueChanged(app, event)
      value = app.YLogScaleCheckBox.Value;
      if value
        app.UIAxesAir.YScale = "log";
        app.UIAxesAir.YMinorTick = "off";
      else
        app.UIAxesAir.YScale = "linear";
        app.UIAxesAir.YMinorTick = "off";
      end
    end

    % Value changed function: DisplayEquationCheckBox
    function DisplayEquationCheckBoxValueChanged(app, event)
      value = app.DisplayEquationCheckBox.Value;
      x = app.XVals;
      y = app.plt.YData;
      if value
        s = display_equation(x, y);
        app.equation = textNW(app.UIAxesAir, s);
      else
        eq = findobj(app.UIAxesAir,'Type','Text');
        delete(eq);
      end
    end

    % Value changing function: ElevationSlider
    function ElevationSliderValueChanging(app, event)
      new_elevation = event.Value;
      app.ElevationDisplay.Value = sprintf('%d m',floor(new_elevation));
      C = mineral_color(app.MineralogyDropDown.Value);
      stokes_vals = stokes_law_model(app.XVals, app.rho_p, new_elevation);
      app.plt=plot(app.UIAxesAir, app.XVals, stokes_vals,...
        'o-','LineWidth',1.5,'MarkerFaceColor','w','Color',C);
      app.UIAxesAir.XMinorTick = 'off';

      % Renew display equation
      if app.DisplayEquationCheckBox.Value
        x = app.XVals;
        y = app.plt.YData;
        s = display_equation(x, y);
        app.equation = textNW(app.UIAxesAir, s);
      end
    end

    % Value changed function: MineralogyDropDown
    function MineralogyDropDownValueChanged(app, event)
      app.rho_p = particle_density(app.MineralogyDropDown.Value); % kg/m^3
      % Update density display
      app.DensityDisplay.Value = sprintf('%g kg/m^3', app.rho_p);

      % Update chart
      C = mineral_color(app.MineralogyDropDown.Value);
      stokes_vals = stokes_law_model(app.XVals, app.rho_p, app.ElevationSlider.Value);
      app.plt=plot(app.UIAxesAir,app.XVals, stokes_vals,...
        'o-','LineWidth',1.5,'MarkerFaceColor','w','Color',C);
      app.UIAxesAir.XMinorTick = 'off';

      % Renew display equation
      if app.DisplayEquationCheckBox.Value
        x = app.XVals;
        y = app.plt.YData;
        s = display_equation(x, y);
        app.equation = textNW(app.UIAxesAir, s);
      end
    end

    % Value changing function: AspectRatioSlider
    function AspectRatioSliderValueChanging(app, event)
      aspect_ratio = event.Value;
      % Update display value
      app.AspectRatioDisplay.Value = sprintf('%0.1f',aspect_ratio);
      % Get size data
      xData = app.XVals;
      % Make adjustment to settling velocity based on updated aspect ratio
      adjustments = zeros(size(xData))';
      for x = 1:length(xData)
        adj = aspect_ratio_adjustment(xData(x)/2, aspect_ratio);
        adjustments(x) = adj;
      end
      
      % Add data back to the plot
      C = mineral_color(app.MineralogyDropDown.Value);
      stokes_vals = stokes_law_model(app.XVals, app.rho_p, app.ElevationSlider.Value);
      app.plt=plot(app.UIAxesAir, app.XVals, stokes_vals' .* adjustments,...
        'o-','LineWidth',1.5,'MarkerFaceColor','w','Color',C);
      app.UIAxesAir.XMinorTick = 'off';

      % Renew display equation
      if app.DisplayEquationCheckBox.Value
        x = app.XVals;
        y = app.plt.YData;
        s = display_equation(x, y);
        app.equation = textNW(app.UIAxesAir, s);
      end
    end

    % Value changed function: XLimSliderWater
    function XLimSliderWaterValueChanged(app, event)
      new_xlimits = app.XLimSliderWater.Value;
      app.XMinBoxWater.Value = new_xlimits(1);
      app.XMaxBoxWater.Value = new_xlimits(2);
      update_axis(app.UIAxesWater, new_xlimits(1), new_xlimits(2))
    end

    % Value changed function: XMinBoxWater
    function XMinBoxWaterValueChanged(app, event)
      new_xmin = app.XMinBoxWater.Value;
      xlim_slider = app.XLimSliderWater.Value;
      app.XLimSliderWater.Value = [new_xmin xlim_slider(2)];
      update_axis(app.UIAxesWater, app.XLimSliderWater.Value(1), app.XLimSliderWater.Value(2))
    end

    % Value changed function: XMaxBoxWater
    function XMaxBoxWaterValueChanged(app, event)
      new_xmax = app.XMaxBoxWater.Value;
      xlim_slider = app.XLimSliderWater.Value;
      app.XLimSliderWater.Value = [xlim_slider(1) new_xmax];
      update_axis(app.UIAxesWater, app.XLimSliderWater.Value(1), app.XLimSliderWater.Value(2))
    end

    % Value changed function: XLogScaleCheckBoxWater
    function XLogScaleCheckBoxWaterValueChanged(app, event)
      value = app.XLogScaleCheckBoxWater.Value;
      if value
        app.UIAxesWater.XScale = "log";
        app.UIAxesWater.XMinorTick = "off";
      else
        app.UIAxesWater.XScale = "linear";
        app.UIAxesWater.XMinorTick = "off";
      end
    end

    % Value changed function: YLogScaleCheckBoxWater
    function YLogScaleCheckBoxWaterValueChanged(app, event)
      value = app.YLogScaleCheckBoxWater.Value;
      if value
        app.UIAxesWater.YScale = "log";
        app.UIAxesWater.YMinorTick = "off";
      else
        app.UIAxesWater.YScale = "linear";
        app.UIAxesWater.YMinorTick = "off";
      end
    end

    % Value changed function: DisplayEquationCheckBoxWater
    function DisplayEquationCheckBoxWaterValueChanged(app, event)
      value = app.DisplayEquationCheckBoxWater.Value;
      x = app.XVals;
      y = app.pltw.YData;
      if value
        s = display_equation(x, y);
        app.equationw = textNW(app.UIAxesWater, s);
      else
        eq = findobj(app.UIAxesWater,'Type','Text');
        delete(eq);
      end
    end

    % Value changed function: MineralogyDropDownWater
    function MineralogyDropDownWaterValueChanged(app, event)
      app.rho_pw = particle_density(app.MineralogyDropDownWater.Value); % kg/m^3
      % Update density display
      app.ParticleDensityDisplayWater.Value = sprintf('%g kg/m^3', app.rho_pw);

      % Update chart
      C = mineral_color(app.MineralogyDropDownWater.Value);
      stokes_vals = stokes_law_model_water(app.XVals, app.rho_pw, app.rho_fw, app.mu_f);
      app.pltw=plot(app.UIAxesWater,app.XVals,stokes_vals,...
        'd-','LineWidth',1.5,'MarkerFaceColor','w','Color',C);
      app.UIAxesWater.XMinorTick = 'off';

      % Renew display equation
      if app.DisplayEquationCheckBoxWater.Value
        x = app.XVals;
        y = app.pltw.YData;
        s = display_equation(x, y);
        app.equation = textNW(app.UIAxesWater, s);
      end
    end

    % Value changed function: TemperatureSlider
    function TemperatureSliderValueChanged(app, event)
      % Update Stokes values
      new_temp = app.TemperatureSlider.Value;
      app.TemperatureDisplay.Value = sprintf('%g °C',new_temp);
      app.rho_fw = water_density_model(app.TemperatureSlider.Value);
      app.mu_f = water_viscosity_model(app.TemperatureSlider.Value);
      app.WaterDensityDisplay.Value = sprintf('%g kg/m^3',...
                                              app.rho_fw);
      app.WaterViscosityDisplay.Value = sprintf('%0.3f mPa/s',...
                                                app.mu_f*1000);
      % Update axes / chart
      C = mineral_color(app.MineralogyDropDownWater.Value);
      stokes_vals = stokes_law_model_water(app.XVals, app.rho_pw, app.rho_fw, app.mu_f);
      app.pltw=plot(app.UIAxesWater, app.XVals, stokes_vals,...
        'd-','LineWidth',1.5,'MarkerFaceColor','w','Color',C);
      app.UIAxesWater.XMinorTick = 'off';

      % Renew display equation
      if app.DisplayEquationCheckBoxWater.Value
        x = app.XVals;
        y = app.pltw.YData;
        s = display_equation(x, y);
        app.equation = textNW(app.UIAxesWater, s);
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.TabGroup = uitabgroup(app.UIFigure);
      app.AtmosphereTab = uitab(app.TabGroup);
      app.AtmosphereTab.Title = 'Atmosphere';
      app.UIAxesAir = uiaxes(app.AtmosphereTab);
      title(app.UIAxesAir, 'Stokes'' Law - Dust Settling Velocity in the Atmosphere')
      xlabel(app.UIAxesAir, 'Particle Diameter (µm)')
      ylabel(app.UIAxesAir, 'Velocity (cm / hour)')
      app.UIAxesAir.XLim = [0.5 20];
      app.ModelParametersPanelAir = uipanel(app.AtmosphereTab);
      app.ModelParametersPanelAir.Title = 'Model Parameters';
      app.AspectRatioDisplay = uitextarea(app.ModelParametersPanelAir);
      app.AspectRatioDisplay.Tooltip = {''};
      app.DensityDisplay = uitextarea(app.ModelParametersPanelAir);
      app.ElevationDisplay = uitextarea(app.ModelParametersPanelAir);
      app.ElevationmAGLSliderLabel = uilabel(app.ModelParametersPanelAir);
      app.ElevationmAGLSliderLabel.Text = {'Elevation'; '(m AGL)'};
      app.ElevationSlider = uislider(app.ModelParametersPanelAir);
      app.ElevationSlider.Limits = [0 8000];
      app.ElevationSlider.MajorTicks = [0 1000 2000 3000 4000 5000 6000 7000 8000];
      app.ElevationSlider.ValueChangingFcn = createCallbackFcn(app, @ElevationSliderValueChanging, true);
      app.ElevationSlider.MinorTicks = [0 250 500 750 1000 1250 1500 1750 2000 2250 2500 2750 3000 3250 3500 3750 4000 4250 4500 4750 5000 5250 5500 5750 6000 6250 6500 6750 7000 7250 7500 7750 8000];
      app.ParticleMineralogyDropDownLabel = uilabel(app.ModelParametersPanelAir);
      app.ParticleMineralogyDropDownLabel.Text = {'Particle'; 'Mineralogy'};
      app.MineralogyDropDown = uidropdown(app.ModelParametersPanelAir);
      app.MineralogyDropDown.Items = {'Average Crustal', 'Calcite', 'Chlorite', 'Feldspar', 'Fe-oxides', 'Goethite', 'Gypsum', 'Hematite', 'Illite', 'Kaolinite', 'Mica', 'Smectite', 'Quartz', 'Vermiculite'};
      app.MineralogyDropDown.ValueChangedFcn = createCallbackFcn(app, @MineralogyDropDownValueChanged, true);
      app.MineralogyDropDown.Value = 'Average Crustal';
      app.AspectRatioSliderLabel = uilabel(app.ModelParametersPanelAir);
      app.AspectRatioSliderLabel.Tooltip = {'Aspect Ratio correction. ONLY ACCURATE FOR DIAMETERS LESS THAN 10 MICRONS!'};
      app.AspectRatioSliderLabel.Text = {'Aspect'; 'Ratio'};
      app.AspectRatioSlider = uislider(app.ModelParametersPanelAir);
      app.AspectRatioSlider.Limits = [1 10];
      app.AspectRatioSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
      app.AspectRatioSlider.ValueChangingFcn = createCallbackFcn(app, @AspectRatioSliderValueChanging, true);
      app.AspectRatioSlider.MinorTicks = [1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10];
      app.AspectRatioSlider.Tooltip = {''};
      app.AspectRatioSlider.Value = 1;
      app.DisplayEquationCheckBox = uicheckbox(app.AtmosphereTab);
      app.DisplayEquationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayEquationCheckBoxValueChanged, true);
      app.DisplayEquationCheckBox.Text = 'Display Equation';
      app.YLogScaleCheckBox = uicheckbox(app.AtmosphereTab);
      app.YLogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @YLogScaleCheckBoxValueChanged, true);
      app.YLogScaleCheckBox.Text = 'Logscale (Y-Axis)';
      app.XLogScaleCheckBox = uicheckbox(app.AtmosphereTab);
      app.XLogScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @XLogScaleCheckBoxValueChanged, true);
      app.XLogScaleCheckBox.Text = 'Logscale (X-Axis)';
      app.XLimSliderAir = uislider(app.AtmosphereTab, 'range');
      app.XLimSliderAir.Limits = [0.01 100];
      app.XLimSliderAir.MajorTicks = [0.01 10 20 30 40 50 60 70 80 90 100];
      app.XLimSliderAir.ValueChangedFcn = createCallbackFcn(app, @XLimSliderAirValueChanged, true);
      app.XLimSliderAir.ValueChangingFcn = createCallbackFcn(app, @XLimSliderAirValueChanging, true);
      app.XLimSliderAir.MinorTicks = [0.01 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
      app.XLimSliderAir.Value = [0.63 16];
      app.XMaxBoxAir = uieditfield(app.AtmosphereTab, 'numeric');
      app.XMaxBoxAir.Limits = [0.01 100];
      app.XMaxBoxAir.ValueDisplayFormat = '%0.2f';
      app.XMaxBoxAir.ValueChangedFcn = createCallbackFcn(app, @XMaxBoxAirValueChanged, true);
      app.XMaxBoxAir.Value = 16;
      app.XMinBoxAir = uieditfield(app.AtmosphereTab, 'numeric');
      app.XMinBoxAir.Limits = [0.01 100];
      app.XMinBoxAir.ValueDisplayFormat = '%0.2f';
      app.XMinBoxAir.ValueChangedFcn = createCallbackFcn(app, @XMinBoxAirValueChanged, true);
      app.XMinBoxAir.Value = 0.63;
      app.WaterTab = uitab(app.TabGroup);
      app.WaterTab.Title = 'Water';
      app.UIAxesWater = uiaxes(app.WaterTab);
      title(app.UIAxesWater, 'Stokes'' Law - Dust Settling Velocity in Water')
      xlabel(app.UIAxesWater, 'Particle Diameter (µm)')
      ylabel(app.UIAxesWater, 'Velocity (cm / hour)')
      app.UIAxesWater.XLim = [0.5 20];
      app.ModelParametersPanelWater = uipanel(app.WaterTab);
      app.ModelParametersPanelWater.Title = 'Model Parameters';
      app.ParticleDensityDisplayWater = uitextarea(app.ModelParametersPanelWater);
      app.ParticleMineralogyLabel = uilabel(app.ModelParametersPanelWater);
      app.ParticleMineralogyLabel.Text = {'Particle'; 'Mineralogy'};
      app.MineralogyDropDownWater = uidropdown(app.ModelParametersPanelWater);
      app.MineralogyDropDownWater.Items = {'Average Crustal', 'Calcite', 'Chlorite', 'Feldspar', 'Fe-oxides', 'Goethite', 'Gypsum', 'Hematite', 'Illite', 'Kaolinite', 'Mica', 'Smectite', 'Quartz', 'Vermiculite'};
      app.MineralogyDropDownWater.ValueChangedFcn = createCallbackFcn(app, @MineralogyDropDownWaterValueChanged, true);
      app.MineralogyDropDownWater.Value = 'Average Crustal';
      app.TemperatureofthewaterSliderLabel = uilabel(app.ModelParametersPanelWater);
      app.TemperatureofthewaterSliderLabel.Text = {'Temperature'; 'of the water'};
      app.TemperatureSlider = uislider(app.ModelParametersPanelWater);
      app.TemperatureSlider.ValueChangedFcn = createCallbackFcn(app, @TemperatureSliderValueChanged, true);
      app.TemperatureSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
      app.TemperatureSlider.Value = 20;
      app.TemperatureDisplay = uitextarea(app.ModelParametersPanelWater);
      app.DensityTextAreaLabel = uilabel(app.ModelParametersPanelWater);
      app.DensityTextAreaLabel.Text = 'Density';
      app.WaterDensityDisplay = uitextarea(app.ModelParametersPanelWater);
      app.DynamicviscosityTextAreaLabel = uilabel(app.ModelParametersPanelWater);
      app.DynamicviscosityTextAreaLabel.Text = {'Dynamic'; 'viscosity'};
      app.WaterViscosityDisplay = uitextarea(app.ModelParametersPanelWater);
      app.XLimSliderWater = uislider(app.WaterTab, 'range');
      app.XLimSliderWater.Limits = [0.01 100];
      app.XLimSliderWater.MajorTicks = [0.01 10 20 30 40 50 60 70 80 90 100];
      app.XLimSliderWater.ValueChangedFcn = createCallbackFcn(app, @XLimSliderWaterValueChanged, true);
      app.XLimSliderWater.MinorTicks = [0.01 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
      app.XLimSliderWater.Value = [0.63 16];
      app.XMaxBoxWater = uieditfield(app.WaterTab, 'numeric');
      app.XMaxBoxWater.Limits = [0.01 100];
      app.XMaxBoxWater.ValueDisplayFormat = '%0.2f';
      app.XMaxBoxWater.ValueChangedFcn = createCallbackFcn(app, @XMaxBoxWaterValueChanged, true);
      app.XMaxBoxWater.Value = 16;
      app.XMinBoxWater = uieditfield(app.WaterTab, 'numeric');
      app.XMinBoxWater.Limits = [0.01 100];
      app.XMinBoxWater.ValueDisplayFormat = '%0.2f';
      app.XMinBoxWater.ValueChangedFcn = createCallbackFcn(app, @XMinBoxWaterValueChanged, true);
      app.XMinBoxWater.Value = 0.63;
      app.DisplayEquationCheckBoxWater = uicheckbox(app.WaterTab);
      app.DisplayEquationCheckBoxWater.ValueChangedFcn = createCallbackFcn(app, @DisplayEquationCheckBoxWaterValueChanged, true);
      app.DisplayEquationCheckBoxWater.Text = 'Display Equation';
      app.YLogScaleCheckBoxWater = uicheckbox(app.WaterTab);
      app.YLogScaleCheckBoxWater.ValueChangedFcn = createCallbackFcn(app, @YLogScaleCheckBoxWaterValueChanged, true);
      app.YLogScaleCheckBoxWater.Text = 'Logscale (Y-Axis)';
      app.XLogScaleCheckBoxWater = uicheckbox(app.WaterTab);
      app.XLogScaleCheckBoxWater.ValueChangedFcn = createCallbackFcn(app, @XLogScaleCheckBoxWaterValueChanged, true);
      app.XLogScaleCheckBoxWater.Text = 'Logscale (X-Axis)';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = StokesSimulator

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end